classdef AutomatedAnnotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusBox                       matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        SetupApplyParametersButton      matlab.ui.control.Button
        SetupCalibrationTargetParametersPanel  matlab.ui.container.Panel
        SetupSelectTargetTypeButtonGroup  matlab.ui.container.ButtonGroup
        SetupFullGridButton             matlab.ui.control.RadioButton
        SetupAprilTagButton             matlab.ui.control.RadioButton
        SetupGridButton                 matlab.ui.control.RadioButton
        SetupTabGroup                   matlab.ui.container.TabGroup
        SetupGridTab                    matlab.ui.container.Tab
        SetupSquareSizeEditField        matlab.ui.control.EditField
        SquareSizemmEditFieldLabel      matlab.ui.control.Label
        SetupAprilTagTab                matlab.ui.container.Tab
        SetupRosbagParametersPanel      matlab.ui.container.Panel
        SetupDataFolderEditField        matlab.ui.control.EditField
        DataFolderLabel                 matlab.ui.control.Label
        SetupSelectCalibrationButtonGroup  matlab.ui.container.ButtonGroup
        SetupUseLocalFileButton         matlab.ui.control.RadioButton
        SetupParseRosbagButton          matlab.ui.control.RadioButton
        SetupCalibrationFileEditField   matlab.ui.control.EditField
        CalibrationFileEditFieldLabel   matlab.ui.control.Label
        SetupCameraInfoTopicEditField   matlab.ui.control.EditField
        CameraInfoTopicEditFieldLabel   matlab.ui.control.Label
        SetupDepthImageTopicEditField   matlab.ui.control.EditField
        DepthImageTopicEditFieldLabel   matlab.ui.control.Label
        SetupRGBImageTopicEditField     matlab.ui.control.EditField
        RGBImageTopicEditFieldLabel     matlab.ui.control.Label
        SetupAnnotationRosbagPathEditField  matlab.ui.control.EditField
        RosbagPathLabel                 matlab.ui.control.Label
        GeometricAligningTab            matlab.ui.container.Tab
        GeometricAligningAlignButton    matlab.ui.control.Button
        GeometricAligningViewerPanel    matlab.ui.container.Panel
        GeometricAligningUseSpatialAveragingButton  matlab.ui.control.CheckBox
        GeometricAligningBlockSizeEditField  matlab.ui.control.NumericEditField
        GeometricAligningBlockSizeLabel  matlab.ui.control.Label
        GeometricAligningUseVisibilityDetectorButton  matlab.ui.control.CheckBox
        GeometricAligningShowKeypointNumbers  matlab.ui.control.CheckBox
        GeometricAligningControllerPanel  matlab.ui.container.Panel
        GeometricAligningFrameSkipsEditField  matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel_6  matlab.ui.control.Label
        GeometricAligningCurrentFrameEditField  matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_12  matlab.ui.control.Label
        GeometricAligningAddFramesButton  matlab.ui.control.Button
        GeometricAligningSubtractFramesButton  matlab.ui.control.Button
        GeometricAligningBeginButton    matlab.ui.control.Button
        GeometricAligningFinishButton   matlab.ui.control.Button
        GeometricAligningModifyKeypointsPanel  matlab.ui.container.Panel
        GeometricAligningAddKeypointButton  matlab.ui.control.Button
        GeometricAligningCurrentDepthEditField  matlab.ui.control.NumericEditField
        DepthmmLabel_2                  matlab.ui.control.Label
        GeometricAligningAddDepthButton  matlab.ui.control.Button
        GeometricAligningSubtractDepthButton  matlab.ui.control.Button
        GeometricAligningKeypointsListBox  matlab.ui.control.ListBox
        ListBoxLabel_2                  matlab.ui.control.Label
        AnnotatingTab                   matlab.ui.container.Tab
        AnnotatingViewerPanel           matlab.ui.container.Panel
        AnnotatingUseSpatialAveragingButton  matlab.ui.control.CheckBox
        AnnotatingBlockSizeEditField    matlab.ui.control.NumericEditField
        AnnotatingBlockSizeLabel        matlab.ui.control.Label
        AnnotatingUseVisibilityDetectorButton  matlab.ui.control.CheckBox
        AnnotatingShowKeypointNumbers   matlab.ui.control.CheckBox
        AnnotatingControllerPanel       matlab.ui.container.Panel
        AnnotatingFrameSkipsEditField   matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel_5  matlab.ui.control.Label
        AnnotatingCurrentFrameEditField  matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_6  matlab.ui.control.Label
        AnnotatingAddFramesButton       matlab.ui.control.Button
        AnnotatingSubtractFramesButton  matlab.ui.control.Button
        AnnotatingBeginButton           matlab.ui.control.Button
        AnnotatingFinishButton          matlab.ui.control.Button
        AnnotatingModifyKeypointsPanel  matlab.ui.container.Panel
        AnnotatingAddKeypointButton     matlab.ui.control.Button
        AnnotatingCurrentDepthEditField  matlab.ui.control.NumericEditField
        DepthmmLabel                    matlab.ui.control.Label
        AnnotatingAddDepthButton        matlab.ui.control.Button
        AnnotatingSubtractDepthButton   matlab.ui.control.Button
        AnnotatingKeypointsListBox      matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        RecordingTab                    matlab.ui.container.Tab
        RecordingBeginButton            matlab.ui.control.Button
        RecordingStartRecordingButton   matlab.ui.control.Button
        RecordingParametersPanel        matlab.ui.container.Panel
        RecordingFrameSkipsEditField    matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel_2  matlab.ui.control.Label
        RecordingUseSpatialFilterCheckBox  matlab.ui.control.CheckBox
        RecordingUseTemporalFilterCheckBox  matlab.ui.control.CheckBox
        RecordingBlockSizeEditField     matlab.ui.control.NumericEditField
        BlockSizeLabel                  matlab.ui.control.Label
        RecordingSlidingWindowSizeEditField  matlab.ui.control.NumericEditField
        SlidingWindowSizeLabel          matlab.ui.control.Label
        RecordingControllerPanel        matlab.ui.container.Panel
        RecordingCurrentFrameEditField  matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label    matlab.ui.control.Label
        RecordingAddFramesButton        matlab.ui.control.Button
        RecordingSubtractFramesButton   matlab.ui.control.Button
        RecordingViewerPanel            matlab.ui.container.Panel
        RecordingShowKeypointNumbersCheckBox  matlab.ui.control.CheckBox
        CadFrameTab                     matlab.ui.container.Tab
        CadFrameFlipZButton             matlab.ui.control.Button
        CadFrameCenterButton            matlab.ui.control.Button
        CadFrameViewerPanel             matlab.ui.container.Panel
        CadFrameUseSpatialFilterCheckBox  matlab.ui.control.CheckBox
        CadFrameBlockSizeEditField      matlab.ui.control.NumericEditField
        CadFrameBlockSizeLabel          matlab.ui.control.Label
        CadFrameUseVisibilityDetectorButton  matlab.ui.control.CheckBox
        CadFrameShowKeypointNumbers     matlab.ui.control.CheckBox
        CadFrameMoveCADFrame            matlab.ui.container.Panel
        CadFrameYEditField              matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_20  matlab.ui.control.Label
        CadFrameYawEditField            matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_19  matlab.ui.control.Label
        CadFramePitchEditField          matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_18  matlab.ui.control.Label
        CadFrameAddYawButton            matlab.ui.control.Button
        CadFrameSubtractYawButton       matlab.ui.control.Button
        CadFrameAddPitchButton          matlab.ui.control.Button
        CadFrameSubtractPitchButton     matlab.ui.control.Button
        CadFrameRollEditField           matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_17  matlab.ui.control.Label
        CadFrameZEditField              matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_16  matlab.ui.control.Label
        CadFrameAddRollButton           matlab.ui.control.Button
        CadFrameSubtractRollButton      matlab.ui.control.Button
        CadFrameAddZButton              matlab.ui.control.Button
        CadFrameSubtractZButton         matlab.ui.control.Button
        CadFrameAddYButton              matlab.ui.control.Button
        CadFrameSubtractYButton         matlab.ui.control.Button
        CadFrameXEditField              matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_14  matlab.ui.control.Label
        CadFrameAddXButton              matlab.ui.control.Button
        CadFrameSubtractXButton         matlab.ui.control.Button
        CadFrameControllerPanel         matlab.ui.container.Panel
        CadFrameFrameSkipsEditField     matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel_7  matlab.ui.control.Label
        CadFrameCurrentFrameEditField   matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_13  matlab.ui.control.Label
        CadFrameAddFramesButton         matlab.ui.control.Button
        CadFrameSubtractFramesButton    matlab.ui.control.Button
        CadFrameBeginButton             matlab.ui.control.Button
        CadFrameFinishButton            matlab.ui.control.Button
        AveragingTab                    matlab.ui.container.Tab
        AveragingBeginButton            matlab.ui.control.Button
        AveragingFinishButton           matlab.ui.control.Button
        AveragingControllerPanel        matlab.ui.container.Panel
        AveragingAverageButton          matlab.ui.control.Button
        AveragingCurrentFrameEditField  matlab.ui.control.NumericEditField
        CurrentFrameEditFieldLabel      matlab.ui.control.Label
        AveragingAddFramesButton        matlab.ui.control.Button
        AveragingSubtractFramesButton   matlab.ui.control.Button
        AveragingParametersPanel        matlab.ui.container.Panel
        AveragingFrameSkipsEditField    matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel  matlab.ui.control.Label
        AveragingNumFramesToAverageEditField  matlab.ui.control.NumericEditField
        NumberofFramestoAverageEditFieldLabel  matlab.ui.control.Label
        TagAligningTab                  matlab.ui.container.Tab
        AligningBeginButton             matlab.ui.control.Button
        AligningFinishButton            matlab.ui.control.Button
        AligningControllerPanel         matlab.ui.container.Panel
        AligningAverageButton           matlab.ui.control.Button
        AligningCurrentFrameEditField   matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_2  matlab.ui.control.Label
        AligningAddFramesButton         matlab.ui.control.Button
        AligningSubtractFramesButton    matlab.ui.control.Button
        AligingParametersPanel          matlab.ui.container.Panel
        AligningNumFramesToAverageEditField  matlab.ui.control.NumericEditField
        AligningFrameSkipsEditField     matlab.ui.control.NumericEditField
        AprilTagFamilyDropDownLabel     matlab.ui.control.Label
        AligningTagSizeEditField        matlab.ui.control.NumericEditField
        AligningTagIdEditField          matlab.ui.control.NumericEditField
        AligningTagFamilyDropDown       matlab.ui.control.DropDown
        AprilTagSizeLabel_2             matlab.ui.control.Label
        AprilTagSizeLabel               matlab.ui.control.Label
        NumberofFramestoSkipforEditFieldLabel_3  matlab.ui.control.Label
        NumberofFramestoAverageEditFieldLabel_2  matlab.ui.control.Label
        ManualAligningTab               matlab.ui.container.Tab
        ManualAligningViewerPanel       matlab.ui.container.Panel
        ManualAligningUseSpatialFilterCheckBox  matlab.ui.control.CheckBox
        ManualAligningBlockSizeEditField  matlab.ui.control.NumericEditField
        ManualAligningBlockSizeLabel    matlab.ui.control.Label
        ManualAligningUseVisibilityDetectorButton  matlab.ui.control.CheckBox
        ManualAligningShowKeypointNumbers  matlab.ui.control.CheckBox
        ManualAligningBeginButton       matlab.ui.control.Button
        ManualAligningFinishButton      matlab.ui.control.Button
        ManualAligningMoveCADFrame      matlab.ui.container.Panel
        ManualAligningYawEditField      matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_11  matlab.ui.control.Label
        ManualAligningPitchEditField    matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_10  matlab.ui.control.Label
        ManualAligningAddYawButton      matlab.ui.control.Button
        ManualAligningSubtractYawButton  matlab.ui.control.Button
        ManualAligningAddPitchButton    matlab.ui.control.Button
        ManualAligningSubtractPitchButton  matlab.ui.control.Button
        ManualAligningRollEditField     matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_9  matlab.ui.control.Label
        ManualAligningZEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_8  matlab.ui.control.Label
        ManualAligningAddRollButton     matlab.ui.control.Button
        ManualAligningSubtractRollButton  matlab.ui.control.Button
        ManualAligningAddZButton        matlab.ui.control.Button
        ManualAligningSubtractZButton   matlab.ui.control.Button
        ManualAligningYEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_7  matlab.ui.control.Label
        ManualAligningAddYButton        matlab.ui.control.Button
        ManualAligningSubtractYButton   matlab.ui.control.Button
        ManualAligningXEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_5  matlab.ui.control.Label
        ManualAligningAddXButton        matlab.ui.control.Button
        ManualAligningSubtractXButton   matlab.ui.control.Button
        ManualAligningControllerPanel   matlab.ui.container.Panel
        AveragingFrameSkipsEditField_2  matlab.ui.control.NumericEditField
        NumberofFramestoSkipforEditFieldLabel_4  matlab.ui.control.Label
        ManualAligningCurrentFrameEditField  matlab.ui.control.NumericEditField
        CurrentFrameEditField_2Label_3  matlab.ui.control.Label
        ManualAligningAddFramesButton   matlab.ui.control.Button
        ManualAligningSubtractFramesButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        statusHandler;
        calibrationTargetHandler;
        
        rosbagParser;
        currentController;
        currentTab;

        selectedKeypoint = nan;
    end
    
    methods (Access = private)

        function baseParams = formatBaseParams(app)
            baseParams = struct;
            baseParams.statusHandler = app.statusHandler;
            baseParams.calibrationTargetHandler = app.calibrationTargetHandler;
            baseParams.rosbagParser = app.rosbagParser;
            CurrentFrameEditField = findall(app.currentTab, 'Tag', 'CurrentFrame');
            baseParams.currentFrame = CurrentFrameEditField.Value;
            NumFramesToSkipEditField = findall(app.currentTab, 'Tag', 'NumFramesToSkip');
            baseParams.frameSkips = NumFramesToSkipEditField.Value;
        end

        function inputParams = formatAligningInputParams(app)
            inputParams = struct;
            ShowKeypointNumbersButton = findall(app.currentTab, 'Tag', 'ShowKeypointNumbers');
            inputParams.showKeypointNumbers = ShowKeypointNumbersButton.Value;
        end

        function visibilityParams = formatVisibilityParams(app)
            visibilityParams = struct;

            useVisibilityDetectorObj = findall(app.currentTab, 'Tag', 'UseVisibilityDetector');
            if ~isempty(useVisibilityDetectorObj)
                visibilityParams.useVisibilityDetector = useVisibilityDetectorObj.Value;
            end

            useTemporalAveragingObj = findall(app.currentTab, 'Tag', 'UseTemporalAveraging');
            if ~isempty(useTemporalAveragingObj)
                visibilityParams.useTemporalAveraging = useTemporalAveragingObj.Value;
            end

            slidingWindowSizeObj = findall(app.currentTab, 'Tag', 'SlidingWindowSize');
            if ~isempty(slidingWindowSizeObj)
                visibilityParams.slidingWindowSize = slidingWindowSizeObj.Value;
            end

            useSpatialAveragingObj = findall(app.currentTab, 'Tag', 'UseSpatialAveraging');
            if ~isempty(useSpatialAveragingObj)
                visibilityParams.useSpatialAveraging = useSpatialAveragingObj.Value;
            end

            blockSizeObj = findall(app.currentTab, 'Tag', 'BlockSize');
            if ~isempty(blockSizeObj)
                visibilityParams.blockSize = blockSizeObj.Value;
            end

            toleranceObj = findall(app.currentTab, 'Tag', 'Tolerance');
            if ~isempty(toleranceObj)
                visibilityParams.tolerance = toleranceObj.Value;
            end
        end

        function handleOnSetupTab(app)
            app.currentTab = app.SetupTab;
            app.currentController = nan;
        end

        function handleOnGeometricAligningTab(app)
            app.currentTab = app.GeometricAligningTab;

            baseParams = formatBaseParams(app);          
            inputParams = formatAligningInputParams(app);
            visibilityParams = formatVisibilityParams(app);
            %TODO: Move this
            visibilityParams.updateTemporalFilter = false;

            app.currentController = GeometricAligningController(baseParams, ...
                                                                inputParams, ...
                                                                visibilityParams);
            app.initKeypointsListBox();
        end

        function handleOnAnnotatingTab(app)
            app.currentTab = app.AnnotatingTab;

            baseParams = formatBaseParams(app);          
            inputParams = formatAligningInputParams(app);
            visibilityParams = formatVisibilityParams(app);
            %TODO: Move this
            visibilityParams.updateTemporalFilter = false;

            app.currentController = AnnotatingController(baseParams, ...
                                                         inputParams, ...
                                                         visibilityParams);
            app.initKeypointsListBox();
        end

        function handleOnCadFrameTab(app)
            app.currentTab = app.CadFrameTab;
            baseParams = formatBaseParams(app);          
            inputParams = formatAligningInputParams(app);
            visibilityParams = formatVisibilityParams(app);
            %TODO: Move this
            visibilityParams.updateTemporalFilter = false;

            app.currentController = CadFrameController(baseParams, ...
                                                       inputParams, ...
                                                       visibilityParams);
        end

        function handleOnRecordingTab(app)
            app.currentTab = app.RecordingTab;

            baseParams = formatBaseParams(app);          
            inputParams = formatAligningInputParams(app);
            visibilityParams = formatVisibilityParams(app);
            %TODO: Move this
            visibilityParams.updateTemporalFilter = true;
            visibilityParams.useVisibilityDetector = true;

            app.currentController = RecordingController(baseParams, ...
                                                        inputParams, ...
                                                        visibilityParams);
        end

        function handleOnAligningTab(app)
            app.currentTab = app.TagAligningTab;
            tagParams = struct();
            tagParams.tagSize = app.AligningTagSizeEditField.Value;
            tagParams.tagId = app.AligningTagIdEditField.Value;
            tagParams.tagFamily = app.AligningTagFamilyDropDown.Value;
            app.currentController = AligningController(app.statusHandler, ...
                                                       app.calibrationTargetHandler, ...
                                                       app.aligningRosbagParser, ...
                                                       app.AveragingFrameSkipsEditField.Value, ...
                                                       tagParams);
        end

        function handleOnManualAligningTab(app)
            app.currentTab = app.ManualAligningTab;

            baseParams = formatBaseParams(app);          
            inputParams = formatAligningInputParams(app);
            visibilityParams = formatVisibilityParams(app);
            %TODO: Move this
            visibilityParams.updateTemporalFilter = false;

            app.currentController = ManualAligningController(baseParams, ...
                                                             inputParams, ...
                                                             visibilityParams);
        end

        
        function handleOnAveragingTab(app)
            app.currentTab = app.AveragingTab;
            app.currentController = AveragingController(app.statusHandler, ...
                                                        app.calibrationTargetHandler, ...
                                                        app.annotatingRosbagParser, ...
                                                        app.AveragingFrameSkipsEditField.Value, ...
                                                        app.AveragingNumFramesToAverageEditField.Value);
            
        end
        
        function handleIsReady(app, container)

            % TODO: Need a more robust check here.
            if app.currentTab ~= app.SetupTab
                if app.currentController.isReady
                    app.enableChildren(container);
                else
                    app.disableChildren(container);
                end
            end
        end

        function disableChildren(app, container)
            children = get(container, 'Children');
            set(children(isprop(children,'Enable')), 'Enable', 'off');
            
        end
        
        function enableChildren(app, container)
            children = get(container, 'Children');
            set(children(isprop(children, 'Enable')), 'Enable', 'on');
        end
        
        function initKeypointsListBox(app)
            for keypoint=1:size(app.currentController.annotatedPoints, 1)
                if isempty(app.AnnotatingKeypointsListBox.Items)
                    app.AnnotatingKeypointsListBox.Items = strsplit(num2str(keypoint));
                else
                    app.AnnotatingKeypointsListBox.Items = [app.AnnotatingKeypointsListBox.Items, ... 
                                                  strsplit(num2str(keypoint))];
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.statusHandler = StatusHandler(app.StatusBox);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.currentTab == app.AnnotatingTab
                app.AnnotatingKeypointsListBox.Items = {};
            end

            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SetupTab
                app.handleOnSetupTab();
            elseif selectedTab == app.GeometricAligningTab
                app.handleOnGeometricAligningTab();  
            elseif selectedTab == app.AnnotatingTab
                app.handleOnAnnotatingTab();
            elseif selectedTab == app.RecordingTab
                app.handleOnRecordingTab();
            elseif selectedTab == app.CadFrameTab
                app.handleOnCadFrameTab();
            elseif selectedTab == app.AveragingTab
                app.handleOnAveragingTab();
            elseif selectedTab == app.TagAligningTab
                app.handleOnAligningTab();
            elseif selectedTab == app.ManualAligningTab
                app.handleOnManualAligningTab();      
            end
            app.handleIsReady(app.currentTab);
        end

        % Button pushed function: SetupApplyParametersButton
        function SetupApplyParametersButtonPushed(app, event)
            app.rosbagParser = RosbagParser(app.statusHandler, ...
                                            app.SetupAnnotationRosbagPathEditField.Value, ...
                                            app.SetupRGBImageTopicEditField.Value, ...
                                            app.SetupDepthImageTopicEditField.Value, ...
                                            app.SetupCameraInfoTopicEditField.Value, ...
                                            app.SetupCalibrationFileEditField.Value, ...
                                            app.SetupUseLocalFileButton.Value, ...
                                            app.SetupDataFolderEditField.Value);
            
            if app.SetupGridButton.Value == 1
                app.calibrationTargetHandler = GridCalibrationTargetHandler(app.statusHandler, ...
                                                                            app.rosbagParser.intrinsics, ...
                                                                            str2double(app.SetupSquareSizeEditField.Value));
            elseif app.SetupAprilTagButton.Value == 1
                app.calibrationTargetHandler = AprilTagCalibrationTargetHandler(app.statusHandler, ...
                                                                                app.annotatingRosbagParser.intrinsics, ...
                                                                                0,0,0);
            elseif app.SetupFullGridButton.Value == 1
                app.calibrationTargetHandler = FullGridCalibrationTargetHandler(app.statusHandler, ...
                                                                            app.rosbagParser, ...
                                                                            str2double(app.SetupSquareSizeEditField.Value));
            end

        end

        % Button pushed function: AligningBeginButton, 
        % AnnotatingBeginButton, AveragingBeginButton, 
        % CadFrameBeginButton, GeometricAligningBeginButton, 
        % ManualAligningBeginButton, RecordingBeginButton
        function BeginButtonPushed(app, event)
            app.currentController.handleBegin();
            event.Source.Enable = false;
        end

        % Value changed function: AligningNumFramesToAverageEditField, 
        % AveragingNumFramesToAverageEditField
        function NumFramesToAverageEditFieldValueChanged(app, event)
            NumFramesToAverageEditField = findall(app.currentTab, 'Tag', 'NumFramesToAverage');
            value = NumFramesToAverageEditField.Value;
            app.currentController.numFramesToAverage = value;
        end

        % Value changed function: AligningFrameSkipsEditField, 
        % AnnotatingFrameSkipsEditField, AveragingFrameSkipsEditField, 
        % CadFrameFrameSkipsEditField, 
        % GeometricAligningFrameSkipsEditField, 
        % RecordingFrameSkipsEditField
        function NumFramesToSkipEditFieldValueChanged(app, event)
            NumFramesToSkipEditField = findall(app.currentTab, 'Tag', 'NumFramesToSkip');
            value = NumFramesToSkipEditField.Value;
            app.currentController.frameSkips = value;
        end

        % Button pushed function: AligningSubtractFramesButton, 
        % AnnotatingSubtractFramesButton, AveragingSubtractFramesButton, 
        % CadFrameSubtractFramesButton, 
        % GeometricAligningSubtractFramesButton, 
        % ManualAligningSubtractFramesButton, RecordingSubtractFramesButton
        function SubtractFramesButtonPushed(app, event)
            app.currentController.handleSubtractFrames();
            CurrentFrameEditField = findall(app.currentTab, 'Tag', 'CurrentFrame');
            CurrentFrameEditField.Value = app.currentController.currentFrame;
        end

        % Value changed function: AligningCurrentFrameEditField, 
        % AnnotatingCurrentFrameEditField, AveragingCurrentFrameEditField, 
        % CadFrameCurrentFrameEditField, 
        % GeometricAligningCurrentFrameEditField, 
        % ManualAligningCurrentFrameEditField, 
        % RecordingCurrentFrameEditField
        function CurrentFrameEditFieldValueChanged(app, event)
            CurrentFrameEditField = findall(app.currentTab, 'Tag', 'CurrentFrame');
            value = CurrentFrameEditField.Value; 
            app.currentController.currentFrame = value;
            CurrentFrameEditField.Value = app.currentController.currentFrame;
        end

        % Button pushed function: AligningAddFramesButton, 
        % AnnotatingAddFramesButton, AveragingAddFramesButton, 
        % CadFrameAddFramesButton, GeometricAligningAddFramesButton, 
        % RecordingAddFramesButton
        function AddFramesButtonPushed(app, event)
            app.currentController.handleAddFrames();
            CurrentFrameEditField = findall(app.currentTab, 'Tag', 'CurrentFrame');
            CurrentFrameEditField.Value = app.currentController.currentFrame;
        end

        % Button pushed function: ManualAligningAddFramesButton
        function AligningAddFramesButtonPushed(app, event)
            AddFramesButtonPushed(app, event);
            % Additional logic for alignment tab to update values on frame
            % switch
            app.currentController.updateState();
            app.ManualAligningXEditField.Value = app.currentController.x;
            app.ManualAligningYEditField.Value = app.currentController.y;
            app.ManualAligningZEditField.Value = app.currentController.z;
            app.ManualAligningRollEditField.Value = app.currentController.roll;
            app.ManualAligningPitchEditField.Value = app.currentController.pitch;
            app.ManualAligningYawEditField.Value = app.currentController.yaw;
        end

        % Button pushed function: AligningAverageButton, 
        % AveragingAverageButton
        function AverageButtonPushed(app, event)
            app.disableChildren(app.currentTab);
            app.currentController.handleAverage(app);
            app.enableChildren(app.currentTab);
        end

        % Button pushed function: AligningFinishButton, 
        % AnnotatingFinishButton, AveragingFinishButton, 
        % CadFrameFinishButton, GeometricAligningFinishButton, 
        % ManualAligningFinishButton
        function FinishButtonPushed(app, event)
            app.currentController.handleFinish();
            event.Source.Enable = true;
        end

        % Button pushed function: AnnotatingAddKeypointButton, 
        % GeometricAligningAddKeypointButton
        function AddKeypointButtonPushed(app, event)
            app.disableChildren(app.currentTab);
            drawnow;
            app.currentController.handleAddKeypoint();
            if app.currentController.addedKeypoint
                KeypointsListBox = findall(app.currentTab, 'Tag', 'KeypointsListBox');
                if isempty(KeypointsListBox.Items)
                    KeypointsListBox.Items = strsplit(num2str(app.currentController.lastKeypoint));
                else
                    KeypointsListBox.Items = [KeypointsListBox.Items, ... 
                                              strsplit(num2str(app.currentController.lastKeypoint))];
                end
            end
            app.enableChildren(app.currentTab);
        end

        % Value changed function: AnnotatingKeypointsListBox, 
        % GeometricAligningKeypointsListBox
        function KeypointsListBoxValueChanged(app, event)
            KeypointsListBox = findall(app.currentTab, 'Tag', 'KeypointsListBox');
            value = KeypointsListBox.Value;
            app.selectedKeypoint = round(str2double(value));
            CurrentDepthEditField = findall(app.currentTab, 'Tag', 'CurrentDepth');
            CurrentDepthEditField.Value = app.currentController.getDepth(app.selectedKeypoint);
        end

        % Button pushed function: AnnotatingSubtractDepthButton, 
        % GeometricAligningSubtractDepthButton
        function SubtractDepthButtonPushed(app, event)
            if ~isnan(app.selectedKeypoint)
                CurrentDepthEditField = findall(app.currentTab, 'Tag', 'CurrentDepth');
                app.currentController.handleSubtractDepth(app.selectedKeypoint);
                CurrentDepthEditField.Value = app.currentController.getDepth(app.selectedKeypoint);
            end
        end

        % Value changed function: AnnotatingCurrentDepthEditField, 
        % GeometricAligningCurrentDepthEditField
        function CurrentDepthEditFieldValueChanged(app, event)
            if ~isnan(app.selectedKeypoint)
                CurrentDepthEditField = findall(app.currentTab, 'Tag', 'CurrentDepth');
                value = CurrentDepthEditField.Value;
                app.currentController.handleSetDepth(app.selectedKeypoint, value);
                CurrentDepthEditField.Value = app.currentController.getDepth(app.selectedKeypoint);
            end
        end

        % Button pushed function: AnnotatingAddDepthButton, 
        % GeometricAligningAddDepthButton
        function AddDepthButtonPushed(app, event)
            if ~isnan(app.selectedKeypoint)
                CurrentDepthEditField = findall(app.currentTab, 'Tag', 'CurrentDepth');
                app.currentController.handleAddDepth(app.selectedKeypoint);
                CurrentDepthEditField.Value = app.currentController.getDepth(app.selectedKeypoint);
            end
        end

        % Value changed function: AnnotatingShowKeypointNumbers, 
        % CadFrameShowKeypointNumbers, 
        % GeometricAligningShowKeypointNumbers, 
        % ManualAligningShowKeypointNumbers, 
        % RecordingShowKeypointNumbersCheckBox
        function ShowKeypointNumbersCheckBoxValueChanged(app, event)
            ShowKeypointNumbersCheckBox = findall(app.currentTab, 'Tag', 'ShowKeypointNumbers');
            value = ShowKeypointNumbersCheckBox.Value;
            app.currentController.showKeypointNumbers = value;
            ShowKeypointNumbersCheckBox.Value = app.currentController.showKeypointNumbers;
        end

        % Value changed function: AnnotatingUseVisibilityDetectorButton, 
        % CadFrameUseVisibilityDetectorButton, 
        % GeometricAligningUseVisibilityDetectorButton, 
        % ManualAligningUseVisibilityDetectorButton
        function UseVisibilityDetectorButtonValueChanged(app, event)
            UseVisibilityDetectorCheckBox = findall(app.currentTab, 'Tag', 'UseVisibilityDetector');
            value = UseVisibilityDetectorCheckBox.Value;
            app.currentController.setUseVisibilityDetector(value);     
            UseVisibilityDetectorCheckBox.Value = app.currentController.getUseVisibilityDetector();
        end

        % Value changed function: RecordingUseTemporalFilterCheckBox
        function UseTemporalFilterButtonValueChanged(app, event)
            UseTemporalFilterCheckbox = findall(app.currentTab, 'Tag', 'UseTemporalFilter');
            value = UseTemporalFilterCheckbox.Value;
            app.currentController.setUseTemporalFilter(value);
            UseTemporalFilterCheckbox.Value = app.currentController.getUseTemporalFilter();
        end

        % Value changed function: RecordingSlidingWindowSizeEditField
        function SlidingWindowSizeEditFieldValueChanged(app, event)
            SlidingWindowSizeEditField = findall(app.currentTab, 'Tag', 'SlidingWindowSize');
            value = SlidingWindowSizeEditField.Value;
            app.currentController.setSlidingWindowSize(value);
            SlidingWindowSizeEditField.Value = app.currentController.getSlidingWindowSize();
        end

        % Value changed function: AnnotatingUseSpatialAveragingButton, 
        % CadFrameUseSpatialFilterCheckBox, 
        % GeometricAligningUseSpatialAveragingButton, 
        % ManualAligningUseSpatialFilterCheckBox
        function UseSpatialFilterButtonValueChanged(app, event)
            UseSpatialFilterCheckbox = findall(app.currentTab, 'Tag', 'UseSpatialFilter');
            value = UseSpatialFilterCheckbox.Value;
            app.currentController.setUseSpatialFilter(value);
            UseSpatialFilterCheckbox.Value = app.currentController.getUseSpatialFilter();
        end

        % Value changed function: CadFrameBlockSizeEditField, 
        % GeometricAligningBlockSizeEditField, RecordingBlockSizeEditField
        function BlockSizeEditFieldValueChanged(app, event)
            BlockSizeEditField = findall(app.currentTab, 'Tag', 'BlockSize');
            value = BlockSizeEditField.Value;
            app.currentController.setBlockSize(value);
            BlockSizeEditField.Value = app.currentController.getBlockSize();
        end

        % Button pushed function: RecordingStartRecordingButton
        function StartRecordingButtonPushed(app, event)
%             app.disableChildren(app.RecordingTab);
%             drawnow;
            app.currentController.handleStartRecording();
        end

        % Value changed function: AligningTagSizeEditField
        function AligningTagSizeEditFieldValueChanged(app, event)
            value = app.AligningTagSizeEditField.Value;
            app.currentController.singleTagDetector.tagSize = value;
            app.AligningTagSizeEditField.Value = app.currentController.singleTagDetector.tagSize;
        end

        % Value changed function: AligningTagIdEditField
        function AligningTagIdEditFieldValueChanged(app, event)
            value = app.AligningTagIdEditField.Value;
            app.currentController.singleTagDetector.tagId = value;
            app.AligningTagIdEditField.Value = app.currentController.singleTagDetector.tagId;
        end

        % Value changed function: AligningTagFamilyDropDown
        function AligningTagFamilyDropDownValueChanged(app, event)
            value = app.AligningTagFamilyDropDown.Value;
            app.currentController.singleTagDetector.tagFamily = value;
        end

        % Button pushed function: CadFrameSubtractXButton, 
        % ManualAligningSubtractXButton
        function SubtractXButtonPushed(app, event)
            app.currentController.handleSubtractX();
            XEditField = findall(app.currentTab, 'Tag', 'X');
            XEditField.Value = app.currentController.x;
        end

        % Value changed function: CadFrameXEditField, 
        % ManualAligningXEditField
        function XEditFieldValueChanged(app, event)
            XEditField = findall(app.currentTab, 'Tag', 'X');
            value = XEditField.Value;
            app.currentController.handleSetX(value);
            XEditField.Value = app.currentController.x;
        end

        % Button pushed function: CadFrameAddXButton, 
        % ManualAligningAddXButton
        function AddXButtonPushed(app, event)
            app.currentController.handleAddX();
            XEditField = findall(app.currentTab, 'Tag', 'X');
            XEditField.Value = app.currentController.x;
        end

        % Button pushed function: CadFrameSubtractYButton, 
        % ManualAligningSubtractYButton
        function SubtractYButtonPushed(app, event)
            app.currentController.handleSubtractY();
            YEditField = findall(app.currentTab, 'Tag', 'Y');
            YEditField.Value = app.currentController.y;
        end

        % Value changed function: CadFrameYEditField, 
        % ManualAligningYEditField
        function YEditFieldValueChanged(app, event)
            YEditField = findall(app.currentTab, 'Tag', 'Y');
            value = YEditField.Value;
            app.currentController.handleSetY(value);
            YEditField.Value = app.currentController.y;
        end

        % Button pushed function: CadFrameAddYButton, 
        % ManualAligningAddYButton
        function AddYButtonPushed(app, event)
            app.currentController.handleAddY();
            YEditField = findall(app.currentTab, 'Tag', 'Y');
            YEditField.Value = app.currentController.y;
        end

        % Button pushed function: CadFrameSubtractZButton, 
        % ManualAligningSubtractZButton
        function SubtractZButtonPushed(app, event)
            app.currentController.handleSubtractZ();
            ZEditField = findall(app.currentTab, 'Tag', 'Z');
            ZEditField.Value = app.currentController.z;
        end

        % Value changed function: CadFrameZEditField, 
        % ManualAligningZEditField
        function ZEditFieldValueChanged(app, event)
            ZEditField = findall(app.currentTab, 'Tag', 'Z');
            value = ZEditField.Value;
            app.currentController.handleSetZ(value);
            ZEditField.Value = app.currentController.z;
        end

        % Button pushed function: CadFrameAddZButton, 
        % ManualAligningAddZButton
        function AddZButtonPushed(app, event)
            app.currentController.handleAddZ();
            ZEditField = findall(app.currentTab, 'Tag', 'Z');
            ZEditField.Value = app.currentController.z;
        end

        % Button pushed function: CadFrameSubtractRollButton, 
        % ManualAligningSubtractRollButton
        function SubtractRollButtonPushed(app, event)
            app.currentController.handleSubtractRoll();
            RollEditField = findall(app.currentTab, 'Tag', 'Roll');
            RollEditField.Value = app.currentController.roll;
        end

        % Value changed function: CadFrameRollEditField, 
        % ManualAligningRollEditField
        function RollEditFieldValueChanged(app, event)
            RollEditField = findall(app.currentTab, 'Tag', 'Roll');
            value = RollEditField.Value;
            app.currentController.handleSetRoll(value);
            RollEditField.Value = app.currentController.roll;          
        end

        % Button pushed function: CadFrameAddRollButton, 
        % ManualAligningAddRollButton
        function AddRollButtonPushed(app, event)
            app.currentController.handleAddRoll();
            RollEditField = findall(app.currentTab, 'Tag', 'Roll');
            RollEditField.Value = app.currentController.roll;
        end

        % Button pushed function: CadFrameSubtractPitchButton, 
        % ManualAligningSubtractPitchButton
        function SubtractPitchButtonPushed(app, event)
            app.currentController.handleSubtractPitch();
            PitchEditField = findall(app.currentTab, 'Tag', 'Pitch');
            PitchEditField.Value = app.currentController.pitch;
        end

        % Value changed function: CadFramePitchEditField, 
        % ManualAligningPitchEditField
        function PitchEditFieldValueChanged(app, event)
            PitchEditField = findall(app.currentTab, 'Tag', 'Pitch');
            value = PitchEditField.Value;
            app.currentController.handleSetPitch(value);
            PitchEditField.Value = app.currentController.pitch;
        end

        % Button pushed function: CadFrameAddPitchButton, 
        % ManualAligningAddPitchButton
        function AddPitchButtonPushed(app, event)
            app.currentController.handleAddPitch();
            PitchEditField = findall(app.currentTab, 'Tag', 'Pitch');
            PitchEditField.Value = app.currentController.pitch;
        end

        % Button pushed function: CadFrameSubtractYawButton, 
        % ManualAligningSubtractYawButton
        function SubtractYawButtonPushed(app, event)
            app.currentController.handleSubtractYaw();
            YawEditField = findall(app.currentTab, 'Tag', 'Yaw');
            YawEditField.Value = app.currentController.yaw;
        end

        % Value changed function: CadFrameYawEditField, 
        % ManualAligningYawEditField
        function YawEditFieldValueChanged(app, event)
            YawEditField = findall(app.currentTab, 'Tag', 'Yaw');
            value = YawEditField.Value;
            app.currentController.handleSetYaw(value);
            YawEditField.Value = app.currentController.yaw; 
        end

        % Button pushed function: CadFrameAddYawButton, 
        % ManualAligningAddYawButton
        function AddYawButtonPushed(app, event)
            app.currentController.handleAddYaw();
            YawEditField = findall(app.currentTab, 'Tag', 'Yaw');
            YawEditField.Value = app.currentController.yaw;
        end

        % Button pushed function: GeometricAligningAlignButton
        function AlignButtonPushed(app, event)
            app.currentController.handleAlign();
        end

        % Button pushed function: CadFrameCenterButton
        function CenterButtonPushed(app, event)
            app.currentController.handleCenter();
        end

        % Button pushed function: CadFrameFlipZButton
        function CadFrameFlipZButtonPushed(app, event)
            app.currentController.handleFlipZ();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SetupRosbagParametersPanel = uipanel(app.SetupTab);
            app.SetupRosbagParametersPanel.Title = 'Rosbag Parameters';
            app.RosbagPathLabel = uilabel(app.SetupRosbagParametersPanel);
            app.RosbagPathLabel.Text = 'Rosbag Path';
            app.SetupAnnotationRosbagPathEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupAnnotationRosbagPathEditField.Value = '/home/subella/Downloads/2022-07-08-16-21-44.bag';
            app.RGBImageTopicEditFieldLabel = uilabel(app.SetupRosbagParametersPanel);
            app.RGBImageTopicEditFieldLabel.Text = 'RGB Image Topic';
            app.SetupRGBImageTopicEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupRGBImageTopicEditField.Value = '/camera/color/image_raw/compressed';
            app.DepthImageTopicEditFieldLabel = uilabel(app.SetupRosbagParametersPanel);
            app.DepthImageTopicEditFieldLabel.Text = 'Depth Image Topic';
            app.SetupDepthImageTopicEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupDepthImageTopicEditField.Value = '/camera/aligned_depth_to_color/image_raw';
            app.CameraInfoTopicEditFieldLabel = uilabel(app.SetupRosbagParametersPanel);
            app.CameraInfoTopicEditFieldLabel.Text = 'Camera Info Topic';
            app.SetupCameraInfoTopicEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupCameraInfoTopicEditField.Value = '/camera/aligned_depth_to_color/image_raw';
            app.CalibrationFileEditFieldLabel = uilabel(app.SetupRosbagParametersPanel);
            app.CalibrationFileEditFieldLabel.Text = 'Calibration File ';
            app.SetupCalibrationFileEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupCalibrationFileEditField.Value = 'Calibrations/Azure.mat';
            app.SetupSelectCalibrationButtonGroup = uibuttongroup(app.SetupRosbagParametersPanel);
            app.SetupSelectCalibrationButtonGroup.Title = 'Select Calibration:';
            app.SetupParseRosbagButton = uiradiobutton(app.SetupSelectCalibrationButtonGroup);
            app.SetupParseRosbagButton.Text = 'Parse Rosbag';
            app.SetupUseLocalFileButton = uiradiobutton(app.SetupSelectCalibrationButtonGroup);
            app.SetupUseLocalFileButton.Text = 'Use Local File';
            app.SetupUseLocalFileButton.Value = true;
            app.DataFolderLabel = uilabel(app.SetupRosbagParametersPanel);
            app.DataFolderLabel.Text = 'Data Folder';
            app.SetupDataFolderEditField = uieditfield(app.SetupRosbagParametersPanel, 'text');
            app.SetupDataFolderEditField.Value = 'FoamTargetVertical';
            app.SetupCalibrationTargetParametersPanel = uipanel(app.SetupTab);
            app.SetupCalibrationTargetParametersPanel.Title = 'Calibration Target Parameters';
            app.SetupTabGroup = uitabgroup(app.SetupCalibrationTargetParametersPanel);
            app.SetupGridTab = uitab(app.SetupTabGroup);
            app.SetupGridTab.Title = 'Grid';
            app.SquareSizemmEditFieldLabel = uilabel(app.SetupGridTab);
            app.SquareSizemmEditFieldLabel.Text = 'Square Size (mm)';
            app.SetupSquareSizeEditField = uieditfield(app.SetupGridTab, 'text');
            app.SetupSquareSizeEditField.Value = '40';
            app.SetupAprilTagTab = uitab(app.SetupTabGroup);
            app.SetupAprilTagTab.Title = 'April Tag';
            app.SetupSelectTargetTypeButtonGroup = uibuttongroup(app.SetupCalibrationTargetParametersPanel);
            app.SetupSelectTargetTypeButtonGroup.Title = 'Select Target Type:';
            app.SetupGridButton = uiradiobutton(app.SetupSelectTargetTypeButtonGroup);
            app.SetupGridButton.Enable = 'off';
            app.SetupGridButton.Text = 'Grid';
            app.SetupAprilTagButton = uiradiobutton(app.SetupSelectTargetTypeButtonGroup);
            app.SetupAprilTagButton.Enable = 'off';
            app.SetupAprilTagButton.Text = 'April Tag';
            app.SetupFullGridButton = uiradiobutton(app.SetupSelectTargetTypeButtonGroup);
            app.SetupFullGridButton.Text = 'Full Grid';
            app.SetupFullGridButton.Value = true;
            app.SetupApplyParametersButton = uibutton(app.SetupTab, 'push');
            app.SetupApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetupApplyParametersButtonPushed, true);
            app.SetupApplyParametersButton.Text = 'Apply Parameters!';
            app.GeometricAligningTab = uitab(app.TabGroup);
            app.GeometricAligningTab.Title = 'Aligning';
            app.GeometricAligningModifyKeypointsPanel = uipanel(app.GeometricAligningTab);
            app.GeometricAligningModifyKeypointsPanel.Title = 'Modify Keypoints';
            app.ListBoxLabel_2 = uilabel(app.GeometricAligningModifyKeypointsPanel);
            app.ListBoxLabel_2.Text = 'List Box';
            app.GeometricAligningKeypointsListBox = uilistbox(app.GeometricAligningModifyKeypointsPanel);
            app.GeometricAligningKeypointsListBox.Items = {};
            app.GeometricAligningKeypointsListBox.ValueChangedFcn = createCallbackFcn(app, @KeypointsListBoxValueChanged, true);
            app.GeometricAligningKeypointsListBox.Tag = 'KeypointsListBox';
            app.GeometricAligningKeypointsListBox.Value = {};
            app.GeometricAligningSubtractDepthButton = uibutton(app.GeometricAligningModifyKeypointsPanel, 'push');
            app.GeometricAligningSubtractDepthButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractDepthButtonPushed, true);
            app.GeometricAligningSubtractDepthButton.Tag = 'SubtractDepth';
            app.GeometricAligningSubtractDepthButton.Text = '-';
            app.GeometricAligningAddDepthButton = uibutton(app.GeometricAligningModifyKeypointsPanel, 'push');
            app.GeometricAligningAddDepthButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepthButtonPushed, true);
            app.GeometricAligningAddDepthButton.Tag = 'AddDepth';
            app.GeometricAligningAddDepthButton.Text = '+';
            app.DepthmmLabel_2 = uilabel(app.GeometricAligningModifyKeypointsPanel);
            app.DepthmmLabel_2.Text = 'Depth (mm)';
            app.GeometricAligningCurrentDepthEditField = uieditfield(app.GeometricAligningModifyKeypointsPanel, 'numeric');
            app.GeometricAligningCurrentDepthEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDepthEditFieldValueChanged, true);
            app.GeometricAligningCurrentDepthEditField.Tag = 'CurrentDepth';
            app.GeometricAligningCurrentDepthEditField.Value = 1;
            app.GeometricAligningAddKeypointButton = uibutton(app.GeometricAligningModifyKeypointsPanel, 'push');
            app.GeometricAligningAddKeypointButton.ButtonPushedFcn = createCallbackFcn(app, @AddKeypointButtonPushed, true);
            app.GeometricAligningAddKeypointButton.Text = 'Add Keypoint';
            app.GeometricAligningFinishButton = uibutton(app.GeometricAligningTab, 'push');
            app.GeometricAligningFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.GeometricAligningFinishButton.Tag = 'Finish';
            app.GeometricAligningFinishButton.Text = {'Finish'; ''};
            app.GeometricAligningBeginButton = uibutton(app.GeometricAligningTab, 'push');
            app.GeometricAligningBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.GeometricAligningBeginButton.Tag = 'Begin';
            app.GeometricAligningBeginButton.Text = 'Begin';
            app.GeometricAligningControllerPanel = uipanel(app.GeometricAligningTab);
            app.GeometricAligningControllerPanel.Title = 'Controller';
            app.GeometricAligningSubtractFramesButton = uibutton(app.GeometricAligningControllerPanel, 'push');
            app.GeometricAligningSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.GeometricAligningSubtractFramesButton.Tag = 'SubtractFrames';
            app.GeometricAligningSubtractFramesButton.Text = '-';
            app.GeometricAligningAddFramesButton = uibutton(app.GeometricAligningControllerPanel, 'push');
            app.GeometricAligningAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.GeometricAligningAddFramesButton.Tag = 'AddFrames';
            app.GeometricAligningAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label_12 = uilabel(app.GeometricAligningControllerPanel);
            app.CurrentFrameEditField_2Label_12.Text = 'Current Frame';
            app.GeometricAligningCurrentFrameEditField = uieditfield(app.GeometricAligningControllerPanel, 'numeric');
            app.GeometricAligningCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.GeometricAligningCurrentFrameEditField.Tag = 'CurrentFrame';
            app.GeometricAligningCurrentFrameEditField.Value = 1;
            app.NumberofFramestoSkipforEditFieldLabel_6 = uilabel(app.GeometricAligningControllerPanel);
            app.NumberofFramestoSkipforEditFieldLabel_6.Text = 'Number of Frames to Skip for +/-';
            app.GeometricAligningFrameSkipsEditField = uieditfield(app.GeometricAligningControllerPanel, 'numeric');
            app.GeometricAligningFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.GeometricAligningFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.GeometricAligningFrameSkipsEditField.Value = 75;
            app.GeometricAligningViewerPanel = uipanel(app.GeometricAligningTab);
            app.GeometricAligningViewerPanel.Title = 'Viewer';
            app.GeometricAligningShowKeypointNumbers = uicheckbox(app.GeometricAligningViewerPanel);
            app.GeometricAligningShowKeypointNumbers.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNumbersCheckBoxValueChanged, true);
            app.GeometricAligningShowKeypointNumbers.Tag = 'ShowKeypointNumbers';
            app.GeometricAligningShowKeypointNumbers.Text = 'Show Keypoint Numbers ';
            app.GeometricAligningUseVisibilityDetectorButton = uicheckbox(app.GeometricAligningViewerPanel);
            app.GeometricAligningUseVisibilityDetectorButton.ValueChangedFcn = createCallbackFcn(app, @UseVisibilityDetectorButtonValueChanged, true);
            app.GeometricAligningUseVisibilityDetectorButton.Tag = 'UseVisibilityDetector';
            app.GeometricAligningUseVisibilityDetectorButton.Text = 'Use Visibility Detector';
            app.GeometricAligningBlockSizeLabel = uilabel(app.GeometricAligningViewerPanel);
            app.GeometricAligningBlockSizeLabel.Text = 'Block Size';
            app.GeometricAligningBlockSizeEditField = uieditfield(app.GeometricAligningViewerPanel, 'numeric');
            app.GeometricAligningBlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeEditFieldValueChanged, true);
            app.GeometricAligningBlockSizeEditField.Tag = 'BlockSize';
            app.GeometricAligningBlockSizeEditField.Value = 2;
            app.GeometricAligningUseSpatialAveragingButton = uicheckbox(app.GeometricAligningViewerPanel);
            app.GeometricAligningUseSpatialAveragingButton.ValueChangedFcn = createCallbackFcn(app, @UseSpatialFilterButtonValueChanged, true);
            app.GeometricAligningUseSpatialAveragingButton.Tag = 'UseSpatialFilter';
            app.GeometricAligningUseSpatialAveragingButton.Text = 'Use Spatial Averaging';
            app.GeometricAligningUseSpatialAveragingButton.Value = true;
            app.GeometricAligningAlignButton = uibutton(app.GeometricAligningTab, 'push');
            app.GeometricAligningAlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.GeometricAligningAlignButton.Tag = 'Begin';
            app.GeometricAligningAlignButton.Text = 'Align!';
            app.AnnotatingTab = uitab(app.TabGroup);
            app.AnnotatingTab.Title = 'Annotating';
            app.AnnotatingModifyKeypointsPanel = uipanel(app.AnnotatingTab);
            app.AnnotatingModifyKeypointsPanel.Title = 'Modify Keypoints';
            app.ListBoxLabel = uilabel(app.AnnotatingModifyKeypointsPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.AnnotatingKeypointsListBox = uilistbox(app.AnnotatingModifyKeypointsPanel);
            app.AnnotatingKeypointsListBox.Items = {};
            app.AnnotatingKeypointsListBox.ValueChangedFcn = createCallbackFcn(app, @KeypointsListBoxValueChanged, true);
            app.AnnotatingKeypointsListBox.Tag = 'KeypointsListBox';
            app.AnnotatingKeypointsListBox.Value = {};
            app.AnnotatingSubtractDepthButton = uibutton(app.AnnotatingModifyKeypointsPanel, 'push');
            app.AnnotatingSubtractDepthButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractDepthButtonPushed, true);
            app.AnnotatingSubtractDepthButton.Tag = 'SubtractDepth';
            app.AnnotatingSubtractDepthButton.Text = '-';
            app.AnnotatingAddDepthButton = uibutton(app.AnnotatingModifyKeypointsPanel, 'push');
            app.AnnotatingAddDepthButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepthButtonPushed, true);
            app.AnnotatingAddDepthButton.Tag = 'AddDepth';
            app.AnnotatingAddDepthButton.Text = '+';
            app.DepthmmLabel = uilabel(app.AnnotatingModifyKeypointsPanel);
            app.DepthmmLabel.Text = 'Depth (mm)';
            app.AnnotatingCurrentDepthEditField = uieditfield(app.AnnotatingModifyKeypointsPanel, 'numeric');
            app.AnnotatingCurrentDepthEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDepthEditFieldValueChanged, true);
            app.AnnotatingCurrentDepthEditField.Tag = 'CurrentDepth';
            app.AnnotatingCurrentDepthEditField.Value = 1;
            app.AnnotatingAddKeypointButton = uibutton(app.AnnotatingModifyKeypointsPanel, 'push');
            app.AnnotatingAddKeypointButton.ButtonPushedFcn = createCallbackFcn(app, @AddKeypointButtonPushed, true);
            app.AnnotatingAddKeypointButton.Tag = 'AddKeypoint';
            app.AnnotatingAddKeypointButton.Text = 'Add Keypoint';
            app.AnnotatingFinishButton = uibutton(app.AnnotatingTab, 'push');
            app.AnnotatingFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.AnnotatingFinishButton.Tag = 'Finish';
            app.AnnotatingFinishButton.Text = {'Finish'; ''};
            app.AnnotatingBeginButton = uibutton(app.AnnotatingTab, 'push');
            app.AnnotatingBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.AnnotatingBeginButton.Tag = 'Begin';
            app.AnnotatingBeginButton.Text = 'Begin';
            app.AnnotatingControllerPanel = uipanel(app.AnnotatingTab);
            app.AnnotatingControllerPanel.Title = 'Controller';
            app.AnnotatingSubtractFramesButton = uibutton(app.AnnotatingControllerPanel, 'push');
            app.AnnotatingSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.AnnotatingSubtractFramesButton.Tag = 'SubtractFrames';
            app.AnnotatingSubtractFramesButton.Text = '-';
            app.AnnotatingAddFramesButton = uibutton(app.AnnotatingControllerPanel, 'push');
            app.AnnotatingAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.AnnotatingAddFramesButton.Tag = 'AddFrames';
            app.AnnotatingAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label_6 = uilabel(app.AnnotatingControllerPanel);
            app.CurrentFrameEditField_2Label_6.Text = 'Current Frame';
            app.AnnotatingCurrentFrameEditField = uieditfield(app.AnnotatingControllerPanel, 'numeric');
            app.AnnotatingCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.AnnotatingCurrentFrameEditField.Tag = 'CurrentFrame';
            app.AnnotatingCurrentFrameEditField.Value = 1;
            app.NumberofFramestoSkipforEditFieldLabel_5 = uilabel(app.AnnotatingControllerPanel);
            app.NumberofFramestoSkipforEditFieldLabel_5.Text = 'Number of Frames to Skip for +/-';
            app.AnnotatingFrameSkipsEditField = uieditfield(app.AnnotatingControllerPanel, 'numeric');
            app.AnnotatingFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.AnnotatingFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.AnnotatingFrameSkipsEditField.Value = 75;
            app.AnnotatingViewerPanel = uipanel(app.AnnotatingTab);
            app.AnnotatingViewerPanel.Title = 'Viewer';
            app.AnnotatingShowKeypointNumbers = uicheckbox(app.AnnotatingViewerPanel);
            app.AnnotatingShowKeypointNumbers.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNumbersCheckBoxValueChanged, true);
            app.AnnotatingShowKeypointNumbers.Tag = 'ShowKeypointNumbers';
            app.AnnotatingShowKeypointNumbers.Text = 'Show Keypoint Numbers ';
            app.AnnotatingUseVisibilityDetectorButton = uicheckbox(app.AnnotatingViewerPanel);
            app.AnnotatingUseVisibilityDetectorButton.ValueChangedFcn = createCallbackFcn(app, @UseVisibilityDetectorButtonValueChanged, true);
            app.AnnotatingUseVisibilityDetectorButton.Tag = 'UseVisibilityDetector';
            app.AnnotatingUseVisibilityDetectorButton.Text = 'Use Visibility Detector';
            app.AnnotatingBlockSizeLabel = uilabel(app.AnnotatingViewerPanel);
            app.AnnotatingBlockSizeLabel.Text = 'Block Size';
            app.AnnotatingBlockSizeEditField = uieditfield(app.AnnotatingViewerPanel, 'numeric');
            app.AnnotatingBlockSizeEditField.Tag = 'BlockSize';
            app.AnnotatingBlockSizeEditField.Value = 2;
            app.AnnotatingUseSpatialAveragingButton = uicheckbox(app.AnnotatingViewerPanel);
            app.AnnotatingUseSpatialAveragingButton.ValueChangedFcn = createCallbackFcn(app, @UseSpatialFilterButtonValueChanged, true);
            app.AnnotatingUseSpatialAveragingButton.Tag = 'UseSpatialFilter';
            app.AnnotatingUseSpatialAveragingButton.Text = 'Use Spatial Averaging';
            app.AnnotatingUseSpatialAveragingButton.Value = true;
            app.RecordingTab = uitab(app.TabGroup);
            app.RecordingTab.Title = 'Recording';
            app.RecordingViewerPanel = uipanel(app.RecordingTab);
            app.RecordingViewerPanel.Title = 'Viewer';
            app.RecordingShowKeypointNumbersCheckBox = uicheckbox(app.RecordingViewerPanel);
            app.RecordingShowKeypointNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNumbersCheckBoxValueChanged, true);
            app.RecordingShowKeypointNumbersCheckBox.Tag = 'ShowKeypointNumbers';
            app.RecordingShowKeypointNumbersCheckBox.Text = 'Show Keypoint Numbers ';
            app.RecordingControllerPanel = uipanel(app.RecordingTab);
            app.RecordingControllerPanel.Title = 'Controller';
            app.RecordingSubtractFramesButton = uibutton(app.RecordingControllerPanel, 'push');
            app.RecordingSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.RecordingSubtractFramesButton.Tag = 'SubtractFrames';
            app.RecordingSubtractFramesButton.Text = '-';
            app.RecordingAddFramesButton = uibutton(app.RecordingControllerPanel, 'push');
            app.RecordingAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.RecordingAddFramesButton.Tag = 'AddFrames';
            app.RecordingAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label = uilabel(app.RecordingControllerPanel);
            app.CurrentFrameEditField_2Label.Text = 'Current Frame';
            app.RecordingCurrentFrameEditField = uieditfield(app.RecordingControllerPanel, 'numeric');
            app.RecordingCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.RecordingCurrentFrameEditField.Tag = 'CurrentFrame';
            app.RecordingCurrentFrameEditField.Value = 1;
            app.RecordingParametersPanel = uipanel(app.RecordingTab);
            app.RecordingParametersPanel.Title = 'Parameters';
            app.SlidingWindowSizeLabel = uilabel(app.RecordingParametersPanel);
            app.SlidingWindowSizeLabel.Text = 'Sliding Window Size';
            app.RecordingSlidingWindowSizeEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.RecordingSlidingWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SlidingWindowSizeEditFieldValueChanged, true);
            app.RecordingSlidingWindowSizeEditField.Tag = 'SlidingWindowSize';
            app.RecordingSlidingWindowSizeEditField.Value = 20;
            app.BlockSizeLabel = uilabel(app.RecordingParametersPanel);
            app.BlockSizeLabel.Text = 'Block Size';
            app.RecordingBlockSizeEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.RecordingBlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeEditFieldValueChanged, true);
            app.RecordingBlockSizeEditField.Tag = 'BlockSize';
            app.RecordingBlockSizeEditField.Value = 2;
            app.RecordingUseTemporalFilterCheckBox = uicheckbox(app.RecordingParametersPanel);
            app.RecordingUseTemporalFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTemporalFilterButtonValueChanged, true);
            app.RecordingUseTemporalFilterCheckBox.Tag = 'UseTemporalFilter';
            app.RecordingUseTemporalFilterCheckBox.Text = 'Use Temporal Averaging';
            app.RecordingUseTemporalFilterCheckBox.Value = true;
            app.RecordingUseSpatialFilterCheckBox = uicheckbox(app.RecordingParametersPanel);
            app.RecordingUseSpatialFilterCheckBox.Tag = 'UseSpatialFilter';
            app.RecordingUseSpatialFilterCheckBox.Text = 'Use Spatial Averaging';
            app.RecordingUseSpatialFilterCheckBox.Value = true;
            app.NumberofFramestoSkipforEditFieldLabel_2 = uilabel(app.RecordingParametersPanel);
            app.NumberofFramestoSkipforEditFieldLabel_2.Text = 'Number of Frames to Skip for +/-';
            app.RecordingFrameSkipsEditField = uieditfield(app.RecordingParametersPanel, 'numeric');
            app.RecordingFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.RecordingFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.RecordingFrameSkipsEditField.Value = 75;
            app.RecordingStartRecordingButton = uibutton(app.RecordingTab, 'push');
            app.RecordingStartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.RecordingStartRecordingButton.Text = 'Start Recording';
            app.RecordingBeginButton = uibutton(app.RecordingTab, 'push');
            app.RecordingBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.RecordingBeginButton.Tag = 'Begin';
            app.RecordingBeginButton.Text = 'Begin';
            app.CadFrameTab = uitab(app.TabGroup);
            app.CadFrameTab.Title = 'CAD Frame';
            app.CadFrameFinishButton = uibutton(app.CadFrameTab, 'push');
            app.CadFrameFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.CadFrameFinishButton.Tag = 'Finish';
            app.CadFrameFinishButton.Text = {'Finish'; ''};
            app.CadFrameBeginButton = uibutton(app.CadFrameTab, 'push');
            app.CadFrameBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.CadFrameBeginButton.Tag = 'Begin';
            app.CadFrameBeginButton.Text = 'Begin';
            app.CadFrameControllerPanel = uipanel(app.CadFrameTab);
            app.CadFrameControllerPanel.Title = 'Controller';
            app.CadFrameSubtractFramesButton = uibutton(app.CadFrameControllerPanel, 'push');
            app.CadFrameSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.CadFrameSubtractFramesButton.Tag = 'SubtractFrames';
            app.CadFrameSubtractFramesButton.Text = '-';
            app.CadFrameAddFramesButton = uibutton(app.CadFrameControllerPanel, 'push');
            app.CadFrameAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.CadFrameAddFramesButton.Tag = 'AddFrames';
            app.CadFrameAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label_13 = uilabel(app.CadFrameControllerPanel);
            app.CurrentFrameEditField_2Label_13.Text = 'Current Frame';
            app.CadFrameCurrentFrameEditField = uieditfield(app.CadFrameControllerPanel, 'numeric');
            app.CadFrameCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.CadFrameCurrentFrameEditField.Tag = 'CurrentFrame';
            app.CadFrameCurrentFrameEditField.Value = 1;
            app.NumberofFramestoSkipforEditFieldLabel_7 = uilabel(app.CadFrameControllerPanel);
            app.NumberofFramestoSkipforEditFieldLabel_7.Text = 'Number of Frames to Skip for +/-';
            app.CadFrameFrameSkipsEditField = uieditfield(app.CadFrameControllerPanel, 'numeric');
            app.CadFrameFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.CadFrameFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.CadFrameFrameSkipsEditField.Value = 75;
            app.CadFrameMoveCADFrame = uipanel(app.CadFrameTab);
            app.CadFrameMoveCADFrame.Title = 'Move CAD Frame';
            app.CadFrameSubtractXButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractXButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractXButtonPushed, true);
            app.CadFrameSubtractXButton.Tag = 'SubtractX';
            app.CadFrameSubtractXButton.Text = '-';
            app.CadFrameAddXButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddXButton.ButtonPushedFcn = createCallbackFcn(app, @AddXButtonPushed, true);
            app.CadFrameAddXButton.Tag = 'AddX';
            app.CadFrameAddXButton.Text = '+';
            app.CurrentFrameEditField_2Label_14 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_14.Text = 'X';
            app.CadFrameXEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFrameXEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.CadFrameXEditField.Tag = 'X';
            app.CadFrameSubtractYButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractYButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractYButtonPushed, true);
            app.CadFrameSubtractYButton.Tag = 'SubtractY';
            app.CadFrameSubtractYButton.Text = '-';
            app.CadFrameAddYButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddYButton.ButtonPushedFcn = createCallbackFcn(app, @AddYButtonPushed, true);
            app.CadFrameAddYButton.Tag = 'AddY';
            app.CadFrameAddYButton.Text = '+';
            app.CadFrameSubtractZButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractZButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractZButtonPushed, true);
            app.CadFrameSubtractZButton.Tag = 'SubtractZ';
            app.CadFrameSubtractZButton.Text = '-';
            app.CadFrameAddZButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddZButton.ButtonPushedFcn = createCallbackFcn(app, @AddZButtonPushed, true);
            app.CadFrameAddZButton.Tag = 'AddZ';
            app.CadFrameAddZButton.Text = '+';
            app.CadFrameSubtractRollButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractRollButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractRollButtonPushed, true);
            app.CadFrameSubtractRollButton.Tag = 'SubtractRoll';
            app.CadFrameSubtractRollButton.Text = '-';
            app.CadFrameAddRollButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddRollButton.ButtonPushedFcn = createCallbackFcn(app, @AddRollButtonPushed, true);
            app.CadFrameAddRollButton.Tag = 'AddRoll';
            app.CadFrameAddRollButton.Text = '+';
            app.CurrentFrameEditField_2Label_16 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_16.Text = 'Z';
            app.CadFrameZEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFrameZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.CadFrameZEditField.Tag = 'Z';
            app.CurrentFrameEditField_2Label_17 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_17.Text = 'Roll';
            app.CadFrameRollEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFrameRollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.CadFrameRollEditField.Tag = 'Roll';
            app.CadFrameSubtractPitchButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractPitchButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractPitchButtonPushed, true);
            app.CadFrameSubtractPitchButton.Tag = 'SubtractPitch';
            app.CadFrameSubtractPitchButton.Text = '-';
            app.CadFrameAddPitchButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddPitchButton.ButtonPushedFcn = createCallbackFcn(app, @AddPitchButtonPushed, true);
            app.CadFrameAddPitchButton.Tag = 'AddPitch';
            app.CadFrameAddPitchButton.Text = '+';
            app.CadFrameSubtractYawButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameSubtractYawButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractYawButtonPushed, true);
            app.CadFrameSubtractYawButton.Tag = 'SubtractYaw';
            app.CadFrameSubtractYawButton.Text = '-';
            app.CadFrameAddYawButton = uibutton(app.CadFrameMoveCADFrame, 'push');
            app.CadFrameAddYawButton.ButtonPushedFcn = createCallbackFcn(app, @AddYawButtonPushed, true);
            app.CadFrameAddYawButton.Tag = 'AddYaw';
            app.CadFrameAddYawButton.Text = '+';
            app.CurrentFrameEditField_2Label_18 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_18.Text = 'Pitch';
            app.CadFramePitchEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFramePitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.CadFramePitchEditField.Tag = 'Pitch';
            app.CurrentFrameEditField_2Label_19 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_19.Text = 'Yaw';
            app.CadFrameYawEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFrameYawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.CadFrameYawEditField.Tag = 'Yaw';
            app.CurrentFrameEditField_2Label_20 = uilabel(app.CadFrameMoveCADFrame);
            app.CurrentFrameEditField_2Label_20.Text = 'Y';
            app.CadFrameYEditField = uieditfield(app.CadFrameMoveCADFrame, 'numeric');
            app.CadFrameYEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.CadFrameYEditField.Tag = 'Y';
            app.CadFrameViewerPanel = uipanel(app.CadFrameTab);
            app.CadFrameViewerPanel.Title = 'Viewer';
            app.CadFrameShowKeypointNumbers = uicheckbox(app.CadFrameViewerPanel);
            app.CadFrameShowKeypointNumbers.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNumbersCheckBoxValueChanged, true);
            app.CadFrameShowKeypointNumbers.Tag = 'ShowKeypointNumbers';
            app.CadFrameShowKeypointNumbers.Text = 'Show Keypoint Numbers ';
            app.CadFrameUseVisibilityDetectorButton = uicheckbox(app.CadFrameViewerPanel);
            app.CadFrameUseVisibilityDetectorButton.ValueChangedFcn = createCallbackFcn(app, @UseVisibilityDetectorButtonValueChanged, true);
            app.CadFrameUseVisibilityDetectorButton.Tag = 'UseVisibilityDetector';
            app.CadFrameUseVisibilityDetectorButton.Text = 'Use Visibility Detector';
            app.CadFrameBlockSizeLabel = uilabel(app.CadFrameViewerPanel);
            app.CadFrameBlockSizeLabel.Text = 'Block Size';
            app.CadFrameBlockSizeEditField = uieditfield(app.CadFrameViewerPanel, 'numeric');
            app.CadFrameBlockSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BlockSizeEditFieldValueChanged, true);
            app.CadFrameBlockSizeEditField.Tag = 'BlockSize';
            app.CadFrameBlockSizeEditField.Value = 2;
            app.CadFrameUseSpatialFilterCheckBox = uicheckbox(app.CadFrameViewerPanel);
            app.CadFrameUseSpatialFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSpatialFilterButtonValueChanged, true);
            app.CadFrameUseSpatialFilterCheckBox.Tag = 'UseSpatialAveraging';
            app.CadFrameUseSpatialFilterCheckBox.Text = 'Use Spatial Averaging';
            app.CadFrameUseSpatialFilterCheckBox.Value = true;
            app.CadFrameCenterButton = uibutton(app.CadFrameTab, 'push');
            app.CadFrameCenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CadFrameCenterButton.Tag = 'Center';
            app.CadFrameCenterButton.Text = 'Center';
            app.CadFrameFlipZButton = uibutton(app.CadFrameTab, 'push');
            app.CadFrameFlipZButton.ButtonPushedFcn = createCallbackFcn(app, @CadFrameFlipZButtonPushed, true);
            app.CadFrameFlipZButton.Tag = 'Center';
            app.CadFrameFlipZButton.Text = {'Flip Z'; ''};
            app.AveragingTab = uitab(app.TabGroup);
            app.AveragingTab.Title = 'Averaging';
            app.AveragingTab.Tag = 'Begin';
            app.AveragingParametersPanel = uipanel(app.AveragingTab);
            app.AveragingParametersPanel.Title = 'Parameters';
            app.NumberofFramestoAverageEditFieldLabel = uilabel(app.AveragingParametersPanel);
            app.NumberofFramestoAverageEditFieldLabel.Text = 'Number of Frames to Average';
            app.AveragingNumFramesToAverageEditField = uieditfield(app.AveragingParametersPanel, 'numeric');
            app.AveragingNumFramesToAverageEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToAverageEditFieldValueChanged, true);
            app.AveragingNumFramesToAverageEditField.Tag = 'NumFramesToAverage';
            app.AveragingNumFramesToAverageEditField.Value = 75;
            app.NumberofFramestoSkipforEditFieldLabel = uilabel(app.AveragingParametersPanel);
            app.NumberofFramestoSkipforEditFieldLabel.Text = 'Number of Frames to Skip for +/-';
            app.AveragingFrameSkipsEditField = uieditfield(app.AveragingParametersPanel, 'numeric');
            app.AveragingFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.AveragingFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.AveragingFrameSkipsEditField.Value = 75;
            app.AveragingControllerPanel = uipanel(app.AveragingTab);
            app.AveragingControllerPanel.Title = 'Controller';
            app.AveragingSubtractFramesButton = uibutton(app.AveragingControllerPanel, 'push');
            app.AveragingSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.AveragingSubtractFramesButton.Tag = 'SubtractFrames';
            app.AveragingSubtractFramesButton.Text = '-';
            app.AveragingAddFramesButton = uibutton(app.AveragingControllerPanel, 'push');
            app.AveragingAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.AveragingAddFramesButton.Tag = 'AddFrames';
            app.AveragingAddFramesButton.Text = '+';
            app.CurrentFrameEditFieldLabel = uilabel(app.AveragingControllerPanel);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.AveragingCurrentFrameEditField = uieditfield(app.AveragingControllerPanel, 'numeric');
            app.AveragingCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.AveragingCurrentFrameEditField.Tag = 'CurrentFrame';
            app.AveragingCurrentFrameEditField.Value = 1;
            app.AveragingAverageButton = uibutton(app.AveragingControllerPanel, 'push');
            app.AveragingAverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AveragingAverageButton.Tag = 'Average';
            app.AveragingAverageButton.Text = {'Average'; ''};
            app.AveragingFinishButton = uibutton(app.AveragingTab, 'push');
            app.AveragingFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.AveragingFinishButton.Tag = 'Finish';
            app.AveragingFinishButton.Text = {'Finish'; ''};
            app.AveragingBeginButton = uibutton(app.AveragingTab, 'push');
            app.AveragingBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.AveragingBeginButton.Text = 'Begin';
            app.TagAligningTab = uitab(app.TabGroup);
            app.TagAligningTab.Title = 'Tag Aligning';
            app.AligingParametersPanel = uipanel(app.TagAligningTab);
            app.AligingParametersPanel.Title = 'Parameters';
            app.NumberofFramestoAverageEditFieldLabel_2 = uilabel(app.AligingParametersPanel);
            app.NumberofFramestoAverageEditFieldLabel_2.Text = 'Number of Frames to Average';
            app.NumberofFramestoSkipforEditFieldLabel_3 = uilabel(app.AligingParametersPanel);
            app.NumberofFramestoSkipforEditFieldLabel_3.Text = 'Number of Frames to Skip for +/-';
            app.AprilTagSizeLabel = uilabel(app.AligingParametersPanel);
            app.AprilTagSizeLabel.Text = 'April Tag Size (mm)';
            app.AprilTagSizeLabel_2 = uilabel(app.AligingParametersPanel);
            app.AprilTagSizeLabel_2.Text = 'April Tag Id';
            app.AligningTagFamilyDropDown = uidropdown(app.AligingParametersPanel);
            app.AligningTagFamilyDropDown.Items = {'tag16h5', 'tag25h9', 'tag36h11', 'tagCircle21h74', 'tagCircle49h12', 'tagCustom48h12', 'tagStandard41h12', 'tagStandard52h13'};
            app.AligningTagFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @AligningTagFamilyDropDownValueChanged, true);
            app.AligningTagFamilyDropDown.Tag = 'AprilTagFamily';
            app.AligningTagFamilyDropDown.Value = 'tag36h11';
            app.AligningTagIdEditField = uieditfield(app.AligingParametersPanel, 'numeric');
            app.AligningTagIdEditField.ValueChangedFcn = createCallbackFcn(app, @AligningTagIdEditFieldValueChanged, true);
            app.AligningTagIdEditField.Tag = 'AprilTagId';
            app.AligningTagIdEditField.Value = 99;
            app.AligningTagSizeEditField = uieditfield(app.AligingParametersPanel, 'numeric');
            app.AligningTagSizeEditField.ValueChangedFcn = createCallbackFcn(app, @AligningTagSizeEditFieldValueChanged, true);
            app.AligningTagSizeEditField.Tag = 'AprilTagSize';
            app.AligningTagSizeEditField.Value = 60;
            app.AprilTagFamilyDropDownLabel = uilabel(app.AligingParametersPanel);
            app.AprilTagFamilyDropDownLabel.Text = 'April Tag Family';
            app.AligningFrameSkipsEditField = uieditfield(app.AligingParametersPanel, 'numeric');
            app.AligningFrameSkipsEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToSkipEditFieldValueChanged, true);
            app.AligningFrameSkipsEditField.Tag = 'NumFramesToSkip';
            app.AligningFrameSkipsEditField.Value = 75;
            app.AligningNumFramesToAverageEditField = uieditfield(app.AligingParametersPanel, 'numeric');
            app.AligningNumFramesToAverageEditField.ValueChangedFcn = createCallbackFcn(app, @NumFramesToAverageEditFieldValueChanged, true);
            app.AligningNumFramesToAverageEditField.Tag = 'NumFramesToAverage';
            app.AligningNumFramesToAverageEditField.Value = 75;
            app.AligningControllerPanel = uipanel(app.TagAligningTab);
            app.AligningControllerPanel.Title = 'Controller';
            app.AligningSubtractFramesButton = uibutton(app.AligningControllerPanel, 'push');
            app.AligningSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.AligningSubtractFramesButton.Tag = 'SubtractFrames';
            app.AligningSubtractFramesButton.Text = '-';
            app.AligningAddFramesButton = uibutton(app.AligningControllerPanel, 'push');
            app.AligningAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFramesButtonPushed, true);
            app.AligningAddFramesButton.Tag = 'AddFrames';
            app.AligningAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label_2 = uilabel(app.AligningControllerPanel);
            app.CurrentFrameEditField_2Label_2.Text = 'Current Frame';
            app.AligningCurrentFrameEditField = uieditfield(app.AligningControllerPanel, 'numeric');
            app.AligningCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.AligningCurrentFrameEditField.Tag = 'CurrentFrame';
            app.AligningCurrentFrameEditField.Value = 1;
            app.AligningAverageButton = uibutton(app.AligningControllerPanel, 'push');
            app.AligningAverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AligningAverageButton.Tag = 'Average';
            app.AligningAverageButton.Text = {'Average'; ''};
            app.AligningFinishButton = uibutton(app.TagAligningTab, 'push');
            app.AligningFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.AligningFinishButton.Tag = 'Finish';
            app.AligningFinishButton.Text = {'Finish'; ''};
            app.AligningBeginButton = uibutton(app.TagAligningTab, 'push');
            app.AligningBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.AligningBeginButton.Tag = 'Begin';
            app.AligningBeginButton.Text = 'Begin';
            app.ManualAligningTab = uitab(app.TabGroup);
            app.ManualAligningTab.Title = 'Manual Aligning';
            app.ManualAligningControllerPanel = uipanel(app.ManualAligningTab);
            app.ManualAligningControllerPanel.Title = 'Controller';
            app.ManualAligningSubtractFramesButton = uibutton(app.ManualAligningControllerPanel, 'push');
            app.ManualAligningSubtractFramesButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFramesButtonPushed, true);
            app.ManualAligningSubtractFramesButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractFramesButton.Text = '-';
            app.ManualAligningAddFramesButton = uibutton(app.ManualAligningControllerPanel, 'push');
            app.ManualAligningAddFramesButton.ButtonPushedFcn = createCallbackFcn(app, @AligningAddFramesButtonPushed, true);
            app.ManualAligningAddFramesButton.Tag = 'AddFrames';
            app.ManualAligningAddFramesButton.Text = '+';
            app.CurrentFrameEditField_2Label_3 = uilabel(app.ManualAligningControllerPanel);
            app.CurrentFrameEditField_2Label_3.Text = 'Current Frame';
            app.ManualAligningCurrentFrameEditField = uieditfield(app.ManualAligningControllerPanel, 'numeric');
            app.ManualAligningCurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.ManualAligningCurrentFrameEditField.Tag = 'CurrentFrame';
            app.ManualAligningCurrentFrameEditField.Value = 1;
            app.NumberofFramestoSkipforEditFieldLabel_4 = uilabel(app.ManualAligningControllerPanel);
            app.NumberofFramestoSkipforEditFieldLabel_4.Text = 'Number of Frames to Skip for +/-';
            app.AveragingFrameSkipsEditField_2 = uieditfield(app.ManualAligningControllerPanel, 'numeric');
            app.AveragingFrameSkipsEditField_2.Tag = 'NumFramesToSkip';
            app.AveragingFrameSkipsEditField_2.Value = 75;
            app.ManualAligningMoveCADFrame = uipanel(app.ManualAligningTab);
            app.ManualAligningMoveCADFrame.Title = 'Move CAD Frame';
            app.ManualAligningSubtractXButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractXButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractXButtonPushed, true);
            app.ManualAligningSubtractXButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractXButton.Text = '-';
            app.ManualAligningAddXButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddXButton.ButtonPushedFcn = createCallbackFcn(app, @AddXButtonPushed, true);
            app.ManualAligningAddXButton.Tag = 'AddFrames';
            app.ManualAligningAddXButton.Text = '+';
            app.CurrentFrameEditField_2Label_5 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_5.Text = 'X';
            app.ManualAligningXEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningXEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.ManualAligningXEditField.Value = 1;
            app.ManualAligningSubtractYButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractYButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractYButtonPushed, true);
            app.ManualAligningSubtractYButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractYButton.Text = '-';
            app.ManualAligningAddYButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddYButton.ButtonPushedFcn = createCallbackFcn(app, @AddYButtonPushed, true);
            app.ManualAligningAddYButton.Tag = 'AddFrames';
            app.ManualAligningAddYButton.Text = '+';
            app.CurrentFrameEditField_2Label_7 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_7.Text = 'Y';
            app.ManualAligningYEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningYEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ManualAligningYEditField.Value = 1;
            app.ManualAligningSubtractZButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractZButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractZButtonPushed, true);
            app.ManualAligningSubtractZButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractZButton.Text = '-';
            app.ManualAligningAddZButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddZButton.ButtonPushedFcn = createCallbackFcn(app, @AddZButtonPushed, true);
            app.ManualAligningAddZButton.Tag = 'AddFrames';
            app.ManualAligningAddZButton.Text = '+';
            app.ManualAligningSubtractRollButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractRollButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractRollButtonPushed, true);
            app.ManualAligningSubtractRollButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractRollButton.Text = '-';
            app.ManualAligningAddRollButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddRollButton.ButtonPushedFcn = createCallbackFcn(app, @AddRollButtonPushed, true);
            app.ManualAligningAddRollButton.Tag = 'AddFrames';
            app.ManualAligningAddRollButton.Text = '+';
            app.CurrentFrameEditField_2Label_8 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_8.Text = 'Z';
            app.ManualAligningZEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ManualAligningZEditField.Value = 1;
            app.CurrentFrameEditField_2Label_9 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_9.Text = 'Roll';
            app.ManualAligningRollEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningRollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.ManualAligningRollEditField.Value = 1;
            app.ManualAligningSubtractPitchButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractPitchButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractPitchButtonPushed, true);
            app.ManualAligningSubtractPitchButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractPitchButton.Text = '-';
            app.ManualAligningAddPitchButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddPitchButton.ButtonPushedFcn = createCallbackFcn(app, @AddPitchButtonPushed, true);
            app.ManualAligningAddPitchButton.Tag = 'AddFrames';
            app.ManualAligningAddPitchButton.Text = '+';
            app.ManualAligningSubtractYawButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningSubtractYawButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractYawButtonPushed, true);
            app.ManualAligningSubtractYawButton.Tag = 'SubtractFrames';
            app.ManualAligningSubtractYawButton.Text = '-';
            app.ManualAligningAddYawButton = uibutton(app.ManualAligningMoveCADFrame, 'push');
            app.ManualAligningAddYawButton.ButtonPushedFcn = createCallbackFcn(app, @AddYawButtonPushed, true);
            app.ManualAligningAddYawButton.Tag = 'AddFrames';
            app.ManualAligningAddYawButton.Text = '+';
            app.CurrentFrameEditField_2Label_10 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_10.Text = 'Pitch';
            app.ManualAligningPitchEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningPitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.ManualAligningPitchEditField.Value = 1;
            app.CurrentFrameEditField_2Label_11 = uilabel(app.ManualAligningMoveCADFrame);
            app.CurrentFrameEditField_2Label_11.Text = 'Yaw';
            app.ManualAligningYawEditField = uieditfield(app.ManualAligningMoveCADFrame, 'numeric');
            app.ManualAligningYawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.ManualAligningYawEditField.Value = 1;
            app.ManualAligningFinishButton = uibutton(app.ManualAligningTab, 'push');
            app.ManualAligningFinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.ManualAligningFinishButton.Tag = 'Finish';
            app.ManualAligningFinishButton.Text = {'Finish'; ''};
            app.ManualAligningBeginButton = uibutton(app.ManualAligningTab, 'push');
            app.ManualAligningBeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.ManualAligningBeginButton.Tag = 'Begin';
            app.ManualAligningBeginButton.Text = 'Begin';
            app.ManualAligningViewerPanel = uipanel(app.ManualAligningTab);
            app.ManualAligningViewerPanel.Title = 'Viewer';
            app.ManualAligningShowKeypointNumbers = uicheckbox(app.ManualAligningViewerPanel);
            app.ManualAligningShowKeypointNumbers.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNumbersCheckBoxValueChanged, true);
            app.ManualAligningShowKeypointNumbers.Tag = 'ShowKeypointNumbers';
            app.ManualAligningShowKeypointNumbers.Text = 'Show Keypoint Numbers ';
            app.ManualAligningUseVisibilityDetectorButton = uicheckbox(app.ManualAligningViewerPanel);
            app.ManualAligningUseVisibilityDetectorButton.ValueChangedFcn = createCallbackFcn(app, @UseVisibilityDetectorButtonValueChanged, true);
            app.ManualAligningUseVisibilityDetectorButton.Tag = 'UseVisibilityDetector';
            app.ManualAligningUseVisibilityDetectorButton.Text = 'Use Visibility Detector';

            app.ManualAligningBlockSizeLabel = uilabel(app.ManualAligningViewerPanel);
            app.ManualAligningBlockSizeLabel.Text = 'Block Size';
            app.ManualAligningBlockSizeEditField = uieditfield(app.ManualAligningViewerPanel, 'numeric');
            app.ManualAligningBlockSizeEditField.Tag = 'BlockSize';
            app.ManualAligningBlockSizeEditField.Value = 2;
            app.ManualAligningUseSpatialFilterCheckBox = uicheckbox(app.ManualAligningViewerPanel);
            app.ManualAligningUseSpatialFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseSpatialFilterButtonValueChanged, true);
            app.ManualAligningUseSpatialFilterCheckBox.Tag = 'UseSpatialAveraging';
            app.ManualAligningUseSpatialFilterCheckBox.Text = 'Use Spatial Averaging';
            app.ManualAligningUseSpatialFilterCheckBox.Value = true;
            app.StatusBox = uitextarea(app.UIFigure);
            app.StatusBox.Tag = 'StatusBox';
            app.StatusBox.Editable = 'off';
            app.StatusBox.Placeholder = 'Hello :)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutomatedAnnotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end